classdef PDSCHLinkApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NRPDSCHLinkUIFigure             matlab.ui.Figure
        NR5GPDSCHLinkLabel              matlab.ui.control.Label
        BW51RBs30kHzsubcarrierspacingNoHARQLabel  matlab.ui.control.Label
        MIMOPanel                       matlab.ui.container.Panel
        NoLayersDropDown                matlab.ui.control.DropDown
        NoLayersDropDownLabel           matlab.ui.control.Label
        NoRxAntsDropDown                matlab.ui.control.DropDown
        NoRxAntsDropDownLabel           matlab.ui.control.Label
        NoTxAntsDropDown                matlab.ui.control.DropDown
        NoTxAntsDropDownLabel           matlab.ui.control.Label
        PDSCHPanel                      matlab.ui.container.Panel
        TargetCodeRateEditField         matlab.ui.control.EditField
        TargetCodeRateEditFieldLabel    matlab.ui.control.Label
        ModulationDropDown              matlab.ui.control.DropDown
        ModulationDropDownLabel         matlab.ui.control.Label
        PDSCHallocationRBsEditField     matlab.ui.control.NumericEditField
        PDSCHallocationRBsEditFieldLabel  matlab.ui.control.Label
        ChannelPanel                    matlab.ui.container.Panel
        PerfectChannelEstimateCheckBox  matlab.ui.control.CheckBox
        DelaySpreadEditField            matlab.ui.control.NumericEditField
        DelaySpreadEditFieldLabel       matlab.ui.control.Label
        MaxDopplerShiftEditField        matlab.ui.control.NumericEditField
        MaxDopplerShiftEditFieldLabel   matlab.ui.control.Label
        DelayProfileDropDown            matlab.ui.control.DropDown
        DelayProfileDropDownLabel       matlab.ui.control.Label
        PerformancePanel                matlab.ui.container.Panel
        EVMperlayerTextArea             matlab.ui.control.TextArea
        EVMperlayerTextAreaLabel        matlab.ui.control.Label
        BlockErrorLamp                  matlab.ui.control.Lamp
        BlockErrorLampLabel             matlab.ui.control.Label
        BLERGauge                       matlab.ui.control.SemicircularGauge
        BLERLabel                       matlab.ui.control.Label
        SimulateButton                  matlab.ui.control.StateButton
        SNRKnob                         matlab.ui.control.Knob
        SNRKnobLabel                    matlab.ui.control.Label
        UIAxesChEst                     matlab.ui.control.UIAxes
        UIAxesConstellation             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        parms;        
        simulationRunning; % Description
        evm;
    end
    
    methods (Access = private)
        
        function setup(app)
            % Load default parameters
            [simParameters] = pdschLinkDefaultParms(); 
            
            % Apply user provided parameters
            simParameters.SNRIn = app.SNRKnob.Value;
            simParameters.PDSCH.Modulation = {app.ModulationDropDown.Value};
            simParameters.PDSCH.TargetCodeRate = eval(string(app.TargetCodeRateEditField.Value));
            simParameters.PDSCH.PRBSet = 0:(app.PDSCHallocationRBsEditField.Value-1);            
            simParameters.NTxAnts = eval(app.NoTxAntsDropDown.Value);
            simParameters.NRxAnts = eval(app.NoRxAntsDropDown.Value);
            simParameters.PDSCH.NLayers = eval(app.NoLayersDropDown.Value);
            
            if simParameters.PDSCH.NLayers > 4              % Multicodeword transmission
                simParameters.PDSCH.ncw = 2;                                        % Number of codewords
                simParameters.PDSCH.TargetCodeRate = simParameters.PDSCH.TargetCodeRate*ones(1,2); 
                simParameters.PDSCH.Modulation = {simParameters.PDSCH.Modulation,simParameters.PDSCH.Modulation};
            else
                simParameters.PDSCH.ncw = 1;                                        % Number of codewords
            end
            
            simParameters.PDSCH.PortSet = 0:simParameters.PDSCH.NLayers-1;
            simParameters.MaximumDopplerShift = app.MaxDopplerShiftEditField.Value;
            simParameters.DelayProfile = app.DelayProfileDropDown.Value;
            simParameters.DelaySpread = app.DelaySpreadEditField.Value;
            simParameters.perfectChannelEstimator = app.PerfectChannelEstimateCheckBox.Value;
            
            % Error checking
            validateNLayers(simParameters);
            if (app.PDSCHallocationRBsEditField.Value>simParameters.NRB)
                error('The number of allocated RBs (%d) cannot be larger than the total bandwidth of (%d) RBs',app.PDSCHallocationRBsEditField.Value,simParameters.NRB)
            end
            
            % Set no layers range            
            app.NoLayersDropDown.Items = noLayersRange(simParameters.NTxAnts, simParameters.NRxAnts);            
            
            % Create gNodeB configuration structure 'gnb' and PDSCH configuration
            % structure 'pdsch'
            gnb = simParameters;
            %pdsch = simParameters.PDSCH;
            
            % Specify additional required fields for PDSCH
            simParameters.PDSCH.RNTI = 1;
            
            % Create objects
            channel = nrPDSCHLinkChannelObject(simParameters);
            [encodeDLSCH,decodeDLSCH] = nrPDSCHLinkDLSCHObjects(simParameters.PDSCH);
            
            % Waveform info and sampling rate             
            waveformInfo = hOFDMInfo(gnb);
            channel.SampleRate = waveformInfo.SamplingRate;
            % Get the maximum number of delayed samples by a channel
            chInfo = info(channel);
            maxChDelay = ceil(max(chInfo.PathDelays*channel.SampleRate)) + chInfo.ChannelFilterDelay;
            
            % Reserve PDSCH Resources Corresponding to SS burst
            % Create SS burst information structure
            ssburst = simParameters.SSBurst;
            ssburst.NCellID = gnb.NCellID;
            ssburst.SampleRate = waveformInfo.SamplingRate;
            
            ssbInfo = hSSBurstInfo(ssburst);
            
            % Map the occupied subcarriers and transmitted symbols of the SS burst
            % (defined in the SS burst numerology) to PDSCH PRBs and symbols in the
            % data numerology
            [mappedPRB,mappedSymbols] = mapNumerology(ssbInfo.OccupiedSubcarriers,ssbInfo.OccupiedSymbols,ssbInfo.SubcarrierSpacing,gnb.SubcarrierSpacing);
            
            % Configure the PDSCH to reserve these resources so that the PDSCH
            % transmission does not overlap the SS burst
            reservation.Symbols = mappedSymbols;
            reservation.PRB = mappedPRB;
            reservation.Period = ssburst.SSBPeriodicity * (gnb.SubcarrierSpacing/15); % Period in slots
            simParameters.PDSCH.Reserved(end+1) = reservation;
            
            % Set up Redundancy Version (RV) sequence
            if simParameters.PDSCH.EnableHARQ
                rvSeq = [0 2 3 1];
            else
                % HARQ disabled - single transmission with RV=0, no retransmissions
                rvSeq = 0;
            end
            
            % Initialize the state of all HARQ processes
            NHARQProcesses = 1;
            harqProcesses = hNewHARQProcesses(NHARQProcesses,rvSeq,simParameters.PDSCH.ncw);
            
            % Antenna array
            c = physconst('LightSpeed'); % propagation speed
            fc = channel.CarrierFrequency;      % carrier frequency
            lambda = c/fc;  % wavelength
            
            % Transmitter antenna array
            TxArray = phased.URA('Size',channel.TransmitAntennaArray.Size(1:2),'ElementSpacing',lambda/2);

            % Create polar plot
            UIAxesPolarPattern = polaraxes(app.NRPDSCHLinkUIFigure);
            UIAxesPolarPattern.Units = 'pixels';
            UIAxesPolarPattern.Position = [360 75 0.9*300 0.9*244];
            UIAxesPolarPattern.RTickLabel=[];
            UIAxesPolarPattern.Title.String = ["Azimuth Tx Pattern" "(multiple plots for multiple layers)"];
            
            % Ch estimate plot labels
            xlabel(app.UIAxesChEst,'symbol');
            ylabel(app.UIAxesChEst,'subcarrier');
            zlabel(app.UIAxesChEst,'Mag (dB)');
            
            % Store values
            app.parms.simParameters = simParameters;
            app.parms.gnb = gnb;
            %app.parms.pdsch = pdsch;
            app.parms.encodeDLSCH = encodeDLSCH;
            app.parms.decodeDLSCH = decodeDLSCH;
            app.parms.channel = channel;
            app.parms.timingOffset = 0;
            app.parms.waveformInfo = waveformInfo;
            app.parms.ssburst = ssburst;
            app.parms.rvSeq = rvSeq;
            app.parms.maxChDelay = maxChDelay;
            app.parms.harqProcesses = harqProcesses;
            app.parms.TxArray = TxArray;
            app.parms.UIAxesPolarPattern = UIAxesPolarPattern;
            
            % Initialize
            app.parms.numPacket = 0;
            app.parms.numPacketErrors = 0;          
            
            % Simulation running flag
            app.simulationRunning = false;
            
            % EVM
            app.evm = comm.EVM();
        end
        
        function simulate(app)
                    
            while app.simulationRunning
                % Load parameters
                simParameters = app.parms.simParameters;
                gnb = app.parms.gnb;
                encodeDLSCH = app.parms.encodeDLSCH;
                decodeDLSCH = app.parms.decodeDLSCH;
                channel = app.parms.channel;
                timingOffset = app.parms.timingOffset;
                waveformInfo = app.parms.waveformInfo;
                ssburst = app.parms.ssburst;
                rvSeq = app.parms.rvSeq;
                maxChDelay = app.parms.maxChDelay;
                harqProcesses = app.parms.harqProcesses;
                numPacket = app.parms.numPacket;
                numPacketErrors = app.parms.numPacketErrors;
                TxArray = app.parms.TxArray;
                pdschRBs = numel(app.parms.simParameters.PDSCH.PRBSet); % length of PDSCH allocation
                evm = app.evm;
                
                pdsch = simParameters.PDSCH;
                
                % Run link
                [chEst,wtx,pdschEq,blkErr,...
                   encodeDLSCH,decodeDLSCH,channel,timingOffset,harqProcesses,evmRMS] = pdschSimulateLink(simParameters,gnb,pdsch,encodeDLSCH,decodeDLSCH,...
                   channel,timingOffset,waveformInfo,ssburst,maxChDelay,harqProcesses,evm);                
                 
                % Calculate packet error rate
                if blkErr
                    numPacketErrors = numPacketErrors+1;
                end
                numPacket = numPacket+1;
                packetErrorRate = 100*(numPacketErrors/numPacket);
                
                % Plot beamforming pattern
                wtxFlipped = flip(wtx,1); % Constellation is flipped to show best points on top, do the same for weights so lpot colours match for each layer
                nLayers = size(wtxFlipped,1);
                patternMatrix = zeros(361,nLayers);
                for n = 1:nLayers
                    TxArray.Taper = wtxFlipped(n,:);
                    patternAz = patternAzimuth(TxArray,channel.CarrierFrequency);
                    patternAz = patternAz + (40-max(patternAz)); % max gain 0dB
                    patternAz(patternAz<0) = 0; % al negative values are zeroed
                    patternMatrix(:,n) = patternAz;
                end
                
                polarplot(app.parms.UIAxesPolarPattern,deg2rad(-180:180).',patternMatrix);
                app.parms.UIAxesPolarPattern.RTickLabel={'-40'; '-30'; '-20'; '-10';'0'};
                app.parms.UIAxesPolarPattern.Title.String = ["Azimuth Tx Pattern" "(multiple plots for multiple layers)"];
                
                % Save state
                app.parms.encodeDLSCH = encodeDLSCH;
                app.parms.decodeDLSCH = decodeDLSCH;
                app.parms.channel = channel;
                app.parms.timingOffset = timingOffset;
                app.parms.harqProcesses = harqProcesses;
                app.parms.numPacket = numPacket;
                app.parms.numPacketErrors = numPacketErrors;
                
                % Update plots
                % Plot constellation
                %plot(app.UIAxesConstellation,pdschEq(:),'.');
                plot(app.UIAxesConstellation,flip(pdschEq,2),'.');
                xlim(app.UIAxesConstellation,[-2 2]);
                ylim(app.UIAxesConstellation,[-2 2]);
                xlabel(app.UIAxesConstellation,'Real');
                ylabel(app.UIAxesConstellation,'Imag');
                
                % Plot channel estimate
                surfl(app.UIAxesChEst,10*log10(abs(chEst(:,:,1))));
                
                % Plot allocation plane
                hold(app.UIAxesChEst,'on');
                allocPlane = zeros(12*pdschRBs,size(chEst,2));
                pep = surfl(app.UIAxesChEst,allocPlane);
                alpha(pep,0.5);
                hold(app.UIAxesChEst,'off');
                % Set colour map
                colormap(app.UIAxesChEst,autumn)
                shading(app.UIAxesChEst,'interp')
                
                % Update block error indicator
                if blkErr
                    app.BlockErrorLamp.Color = 'r';
                else
                    app.BlockErrorLamp.Color = 'g';
                end
                app.BLERGauge.Value = packetErrorRate;
                app.EVMperlayerTextArea.Value = string(evmRMS); 
                
                app.evm = evm;                
                
                % Update plots
                drawnow;
                
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setup(app);
        end

        % Value changed function: SimulateButton
        function SimulateButtonValueChanged(app, event)
            if app.SimulateButton.Value
                app.SimulateButton.Text = 'Pause';
                app.simulationRunning = true;
                simulate(app);
            else
                app.SimulateButton.Text = 'Simulate';
                app.simulationRunning = false;
            end
%              while app.SimulateButton.Value
%                 app.SimulateButton.Text = 'Pause';
%                 simulate(app);
%              end
%              app.SimulateButton.Text = 'Simulate';
        end

        % Value changed function: SNRKnob
        function SNRKnobValueChanged(app, event)
            value = app.SNRKnob.Value;
            app.parms.simParameters.SNRIn = value;
        end

        % Value changed function: ModulationDropDown
        function ModulationDropDownValueChanged(app, event)
            value = app.ModulationDropDown.Value;
            
            simParameters = app.parms.simParameters;
            if simParameters.PDSCH.NLayers > 4              % Multicodeword transmission
                app.parms.simParameters.PDSCH.Modulation = {value,value};
            else
                app.parms.simParameters.PDSCH.Modulation = {value};
            end
        end

        % Value changed function: PDSCHallocationRBsEditField
        function PDSCHallocationRBsEditFieldValueChanged(app, event)
            value = app.PDSCHallocationRBsEditField.Value;
            app.parms.simParameters.PDSCH.PRBSet = 0:value-1;
            
            evm = app.evm;
            evm.release();
            app.evm = evm;
            %app.parms.pdsch.PRBSet = 0:value-1;
        end

        % Value changed function: NoLayersDropDown
        function NoLayersDropDownValueChanged(app, event)
            value = str2double(app.NoLayersDropDown.Value);
            simParameters = app.parms.simParameters;
            simParameters.PDSCH.NLayers = value;
            simParameters.PDSCH.PortSet = 0:value-1;
            validateNLayers(simParameters)
            
            if simParameters.PDSCH.NLayers > 4              % Multicodeword transmission
                simParameters.PDSCH.ncw = 2;                                        % Number of codewords
                simParameters.PDSCH.TargetCodeRate = simParameters.PDSCH.TargetCodeRate(1)*ones(1,2); 
                simParameters.PDSCH.Modulation = {simParameters.PDSCH.Modulation{1},simParameters.PDSCH.Modulation{1}};
            else
                simParameters.PDSCH.ncw = 1;                                        % Number of codewords
            end
            
            app.parms.simParameters = simParameters;
            %app.parms.pdsch = simParameters.PDSCH;
            
            if app.parms.simParameters.PDSCH.EnableHARQ
                rvSeq = [0 2 3 1];
            else
                % HARQ disabled - single transmission with RV=0, no retransmissions
                rvSeq = 0;
            end
            
            NHARQProcesses = 1;
            app.parms.harqProcesses = hNewHARQProcesses(NHARQProcesses,rvSeq,simParameters.PDSCH.ncw);
            
            evm = app.evm;
            evm.release();
            app.evm = evm;
            
        end

        % Value changed function: NoTxAntsDropDown
        function NoTxAntsDropDownValueChanged(app, event)
            value = str2double(app.NoTxAntsDropDown.Value);
            
            app.parms.simParameters.NTxAnts = value;
            app.parms.channel = nrPDSCHLinkChannelObject(app.parms.simParameters,app.parms.channel);
            
            c = physconst('LightSpeed'); % propagation speed
            fc = app.parms.channel.CarrierFrequency;      % carrier frequency
            lambda = c/fc;  % wavelength
            
            % Transmitter antenna array
            app.parms.TxArray = phased.URA('Size',app.parms.channel.TransmitAntennaArray.Size(1:2),'ElementSpacing',lambda/2);
            
            % Calculate maximum number of layers
            app.NoLayersDropDown.Items = noLayersRange(app.parms.simParameters.NTxAnts, app.parms.simParameters.NRxAnts);

        end

        % Value changed function: MaxDopplerShiftEditField
        function MaxDopplerShiftEditFieldValueChanged(app, event)
            value = eval(string(app.MaxDopplerShiftEditField.Value));
            
            app.parms.simParameters.MaximumDopplerShift = value;
            app.parms.channel = nrPDSCHLinkChannelObject(app.parms.simParameters,app.parms.channel);
        end

        % Value changed function: DelayProfileDropDown
        function DelayProfileDropDownValueChanged(app, event)
            value = app.DelayProfileDropDown.Value;
            
            app.parms.simParameters.DelayProfile = value;
            app.parms.channel = nrPDSCHLinkChannelObject(app.parms.simParameters,app.parms.channel);
        end

        % Value changed function: DelaySpreadEditField
        function DelaySpreadEditFieldValueChanged(app, event)
            value = app.DelaySpreadEditField.Value;
            
            app.parms.simParameters.DelaySpread = value;
            app.parms.channel = nrPDSCHLinkChannelObject(app.parms.simParameters,app.parms.channel);
        end

        % Value changed function: NoRxAntsDropDown
        function NoRxAntsDropDownValueChanged(app, event)
            value = str2double(app.NoRxAntsDropDown.Value);
            
            app.parms.simParameters.NRxAnts = value;
            app.parms.channel = nrPDSCHLinkChannelObject(app.parms.simParameters,app.parms.channel);
            
            % Calculate maximum number of layers                        
            app.NoLayersDropDown.Items = noLayersRange(app.parms.simParameters.NTxAnts, app.parms.simParameters.NRxAnts);
            
        end

        % Callback function
        function PerfectChannelEstimateCheckBoxValueChanged(app, event)
            value = app.PerfectChannelEstimateCheckBox.Value;
            
        end

        % Value changed function: PerfectChannelEstimateCheckBox
        function PerfectChannelEstimateCheckBoxValueChanged2(app, event)
            value = app.PerfectChannelEstimateCheckBox.Value;
            app.parms.simParameters.perfectChannelEstimator = value;
        end

        % Value changed function: TargetCodeRateEditField
        function TargetCodeRateEditFieldValueChanged(app, event)
            value = eval(string(app.TargetCodeRateEditField.Value));
            
            simParameters = app.parms.simParameters;
            if simParameters.PDSCH.NLayers > 4              % Multicodeword transmission
                app.parms.simParameters.PDSCH.TargetCodeRate = value*ones(1,2); 
            else
                app.parms.simParameters.PDSCH.TargetCodeRate = value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NRPDSCHLinkUIFigure = uifigure('Visible', 'off');
            app.NRPDSCHLinkUIFigure.Name = 'NR PDSCH Link';
            app.UIAxesConstellation = uiaxes(app.NRPDSCHLinkUIFigure);
            title(app.UIAxesConstellation, 'Equalised Constellation')
            xlabel(app.UIAxesConstellation, 'X')
            ylabel(app.UIAxesConstellation, 'Y')
            app.UIAxesChEst = uiaxes(app.NRPDSCHLinkUIFigure);
            title(app.UIAxesChEst, {'Channel Estimate'; '(Tx 1 - Rx 1)'})
            xlabel(app.UIAxesChEst, 'X')
            ylabel(app.UIAxesChEst, 'Y')
            app.SNRKnobLabel = uilabel(app.NRPDSCHLinkUIFigure);
            app.SNRKnobLabel.Text = 'SNR';
            app.SNRKnob = uiknob(app.NRPDSCHLinkUIFigure, 'continuous');
            app.SNRKnob.Limits = [-30 30];
            app.SNRKnob.ValueChangedFcn = createCallbackFcn(app, @SNRKnobValueChanged, true);
            app.SNRKnob.Value = 20;
            app.SimulateButton = uibutton(app.NRPDSCHLinkUIFigure, 'state');
            app.SimulateButton.ValueChangedFcn = createCallbackFcn(app, @SimulateButtonValueChanged, true);
            app.SimulateButton.Text = 'Simulate';
            app.PerformancePanel = uipanel(app.NRPDSCHLinkUIFigure);
            app.PerformancePanel.Title = 'Performance';
            app.BLERLabel = uilabel(app.PerformancePanel);
            app.BLERLabel.Text = 'BLER (%)';
            app.BLERGauge = uigauge(app.PerformancePanel, 'semicircular');
            app.BlockErrorLampLabel = uilabel(app.PerformancePanel);
            app.BlockErrorLampLabel.Text = 'Block Error';
            app.BlockErrorLamp = uilamp(app.PerformancePanel);
            app.EVMperlayerTextAreaLabel = uilabel(app.PerformancePanel);
            app.EVMperlayerTextAreaLabel.Text = {'EVM (%)'; '(per layer)'};
            app.EVMperlayerTextArea = uitextarea(app.PerformancePanel);
            app.EVMperlayerTextArea.Value = {'0'};
            app.ChannelPanel = uipanel(app.NRPDSCHLinkUIFigure);
            app.ChannelPanel.Title = 'Channel';
            app.DelayProfileDropDownLabel = uilabel(app.ChannelPanel);
            app.DelayProfileDropDownLabel.Text = 'Delay Profile';
            app.DelayProfileDropDown = uidropdown(app.ChannelPanel);
            app.DelayProfileDropDown.Items = {'CDL-A', 'CDL-B', 'CDL-C', 'CDL-D', 'CDL-E'};
            app.DelayProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @DelayProfileDropDownValueChanged, true);
            app.DelayProfileDropDown.Value = 'CDL-C';
            app.MaxDopplerShiftEditFieldLabel = uilabel(app.ChannelPanel);
            app.MaxDopplerShiftEditFieldLabel.Text = 'Max Doppler Shift';
            app.MaxDopplerShiftEditField = uieditfield(app.ChannelPanel, 'numeric');
            app.MaxDopplerShiftEditField.ValueChangedFcn = createCallbackFcn(app, @MaxDopplerShiftEditFieldValueChanged, true);
            app.MaxDopplerShiftEditField.Value = 5;
            app.DelaySpreadEditFieldLabel = uilabel(app.ChannelPanel);
            app.DelaySpreadEditFieldLabel.Text = 'Delay Spread';
            app.DelaySpreadEditField = uieditfield(app.ChannelPanel, 'numeric');
            app.DelaySpreadEditField.ValueDisplayFormat = '%e';
            app.DelaySpreadEditField.ValueChangedFcn = createCallbackFcn(app, @DelaySpreadEditFieldValueChanged, true);
            app.DelaySpreadEditField.Value = 3e-07;
            app.PerfectChannelEstimateCheckBox = uicheckbox(app.ChannelPanel);
            app.PerfectChannelEstimateCheckBox.ValueChangedFcn = createCallbackFcn(app, @PerfectChannelEstimateCheckBoxValueChanged2, true);
            app.PerfectChannelEstimateCheckBox.Text = 'Perfect Channel Estimate';
            app.PerfectChannelEstimateCheckBox.Value = true;
            app.PDSCHPanel = uipanel(app.NRPDSCHLinkUIFigure);
            app.PDSCHPanel.Title = 'PDSCH';
            app.PDSCHallocationRBsEditFieldLabel = uilabel(app.PDSCHPanel);
            app.PDSCHallocationRBsEditFieldLabel.Text = 'PDSCH allocation (RBs)';
            app.PDSCHallocationRBsEditField = uieditfield(app.PDSCHPanel, 'numeric');
            app.PDSCHallocationRBsEditField.ValueChangedFcn = createCallbackFcn(app, @PDSCHallocationRBsEditFieldValueChanged, true);
            app.PDSCHallocationRBsEditField.Value = 10;
            app.ModulationDropDownLabel = uilabel(app.PDSCHPanel);
            app.ModulationDropDownLabel.Text = 'Modulation';
            app.ModulationDropDown = uidropdown(app.PDSCHPanel);
            app.ModulationDropDown.Items = {'QPSK', '16QAM', '64QAM', '256QAM'};
            app.ModulationDropDown.ValueChangedFcn = createCallbackFcn(app, @ModulationDropDownValueChanged, true);
            app.ModulationDropDown.Value = '16QAM';
            app.TargetCodeRateEditFieldLabel = uilabel(app.PDSCHPanel);
            app.TargetCodeRateEditFieldLabel.Text = 'Target Code Rate';
            app.TargetCodeRateEditField = uieditfield(app.PDSCHPanel, 'text');
            app.TargetCodeRateEditField.ValueChangedFcn = createCallbackFcn(app, @TargetCodeRateEditFieldValueChanged, true);
            app.TargetCodeRateEditField.Value = '490/1024';
            app.MIMOPanel = uipanel(app.NRPDSCHLinkUIFigure);
            app.MIMOPanel.Title = 'MIMO';
            app.NoTxAntsDropDownLabel = uilabel(app.MIMOPanel);
            app.NoTxAntsDropDownLabel.Text = 'No Tx Ants';
            app.NoTxAntsDropDown = uidropdown(app.MIMOPanel);
            app.NoTxAntsDropDown.Items = {'4', '8', '16', '32', '64'};
            app.NoTxAntsDropDown.ValueChangedFcn = createCallbackFcn(app, @NoTxAntsDropDownValueChanged, true);
            app.NoTxAntsDropDown.Value = '8';
            app.NoRxAntsDropDownLabel = uilabel(app.MIMOPanel);
            app.NoRxAntsDropDownLabel.Text = 'No Rx Ants';
            app.NoRxAntsDropDown = uidropdown(app.MIMOPanel);
            app.NoRxAntsDropDown.Items = {'2', '4', '8'};
            app.NoRxAntsDropDown.ValueChangedFcn = createCallbackFcn(app, @NoRxAntsDropDownValueChanged, true);
            app.NoRxAntsDropDown.Value = '2';
            app.NoLayersDropDownLabel = uilabel(app.MIMOPanel);
            app.NoLayersDropDownLabel.Text = 'No Layers';
            app.NoLayersDropDown = uidropdown(app.MIMOPanel);
            app.NoLayersDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.NoLayersDropDown.ValueChangedFcn = createCallbackFcn(app, @NoLayersDropDownValueChanged, true);
            app.NoLayersDropDown.Value = '1';
            app.BW51RBs30kHzsubcarrierspacingNoHARQLabel = uilabel(app.NRPDSCHLinkUIFigure);
            app.BW51RBs30kHzsubcarrierspacingNoHARQLabel.Text = {'    - BW: 51 RBs'; '    - 30 kHz subcarrier spacing'; '    - No HARQ'};
            app.NR5GPDSCHLinkLabel = uilabel(app.NRPDSCHLinkUIFigure);
            app.NR5GPDSCHLinkLabel.Text = 'NR 5G PDSCH Link';
            % Show the figure after all components are created
            app.NRPDSCHLinkUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PDSCHLinkApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NRPDSCHLinkUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NRPDSCHLinkUIFigure)
        end
    end
end